classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RecNameLabel                matlab.ui.control.Label
        LoadDataButton              matlab.ui.control.Button
        RecordingnumberSlider       matlab.ui.control.Slider
        RecordingnumberSliderLabel  matlab.ui.control.Label
        SubjectSlider               matlab.ui.control.Slider
        SubjectSliderLabel          matlab.ui.control.Label
        RunCSPButton                matlab.ui.control.Button
        ChannelSlider               matlab.ui.control.Slider
        ChannelSliderLabel          matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG % Description
        FS
        TS
        recName
        recNum
        subject
    end
    
    methods (Access = private)
        function [T0, T1, T2] = getIntervals(app, recName,annotator,fs,sigLen)
            T0=[];
            T1=[];
            T2=[];
            [annot, ~, ~, ~, ~, cmt] = rdann(recName, annotator);
            cmt = string(cmt);
            for i=1:size(annot,1)
                splt=split(cmt(i), ' ');
                dur = str2double(splt(3))*fs;
                intEnd = annot(i) + dur-1;
                if (i<length(annot))
                    intEnd = min(annot(i+1)-1, intEnd);
                else
                    intEnd = min(sigLen, intEnd);
                end
                    
                if (splt(1)=="T0")
                    T0 = [T0; [annot(i) intEnd]];
                elseif (splt(1)=="T1")
                    T1 = [T1; annot(i) intEnd];
                elseif (splt(1)=="T2")
                    T2 = [T2; annot(i) intEnd];
                end
            end
        end
        
        function [result] = f_CSP(app, cdat1, cdat2)
            r0 = (cdat1 * cdat1')/trace(cdat1 * cdat1');
            r1 = (cdat2 * cdat2')/trace(cdat2 * cdat2');
            Rsum = r0 + r1;
            
            [EVecsum,EValsum] = eig(Rsum);
            [EValsum,ind] = sort(diag(EValsum),'descend');
            EVecsum = EVecsum(:,ind);
    
            W = sqrt(inv(diag(EValsum))) * EVecsum';
            
            s0 = W * r0 * W';
            s1 = W * r1 * W';
            
            [B,D] = eig(s0, s1);    
            [D,ind]=sort(diag(D));B=B(:,ind);
            result = B'*W;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.recName = 'eegmmidb/S001/S001R01.edf';
            [ eeg, fs, ts] = rdsamp(app.recName, 1:64);
            eeg = transpose(eeg);
            app.EEG = eeg;
            app.FS = fs;
            app.TS = ts;
            
            plot(app.UIAxes, eeg(1, :))
            
            app.RecNameLabel.Text = strcat('Recording:', ' ', app.recName);
        end

        % Value changing function: ChannelSlider
        function ChannelSliderValueChanging(app, event)
            changingValue = int32(event.Value);
            plot(app.UIAxes, app.EEG(changingValue, :))
            
            app.UIAxes.Title.String = strcat("EEG Signal Preview Channel ", int2str(changingValue));
        end

        % Button pushed function: RunCSPButton
        function RunCSPButtonPushed(app, event)
            [T0, T1, T2] = app.getIntervals(app.recName, 'event', app.FS, size(app.EEG, 2));
            
            c1dat0 = app.EEG(:, T1(1,1):T1(1,2));
            c2dat0 = app.EEG(:, T2(1,1):T2(1,2));

            c1dat1 = app.EEG(:, T1(size(T1,1),1):T1(size(T1,1),2));
            c2dat1 = app.EEG(:, T2(size(T2,1),1):T2(size(T2,1),2));
            
            W = app.f_CSP(c1dat0, c2dat0);
            
            S11 = W*c1dat1;
            S21 = W*c2dat1;

            S11A = [S11(1, :).', S11(size(S11,1),:).'].';
            S21A = [S21(1, :).', S21(size(S21,1),:).'].';
            
            plot(app.UIAxes2, S11A(1,:), S11A(2,:), 'o');
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, S21A(1,:), S21A(2,:), 'x');
        end

        % Value changed function: RecordingnumberSlider
        function RecordingnumberSliderValueChanged(app, event)
            value = app.RecordingnumberSlider.Value;
            app.recNum = int32(value);
        end

        % Value changing function: RecordingnumberSlider
        function RecordingnumberSliderValueChanging(app, event)
            changingValue = event.Value;
            app.recNum = int32(changingValue);
        end

        % Value changing function: SubjectSlider
        function SubjectSliderValueChanging(app, event)
            changingValue = event.Value;
            app.subject = int32(changingValue);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            digits = numel(num2str(app.subject));
            
            nz = 3 - numel(num2str(app.subject));
            subName = 'S';

            for i = 1:nz
                subName = strcat(subName, '0');
            end
            subName = strcat(subName, int2str(app.subject));
            
            if numel(num2str(app.recNum)) < 2
                recN = strcat('0', int2str(app.recNum));
            else
                recN = int2str(app.recNum);
            end
            
            app.recName = strcat('eegmmidb/', subName, '/', strcat(subName, 'R', recN), '.edf');
            
            [ eeg, fs, ts] = rdsamp(app.recName, 1:64);
            eeg = transpose(eeg);
            app.EEG = eeg;
            app.FS = fs;
            app.TS = ts;
            
            plot(app.UIAxes, eeg(1, :))
            
            app.RecNameLabel.Text = strcat('Recording:', ' ', app.recName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG SIgnal Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CSP')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ChannelSliderLabel = uilabel(app.UIFigure);
            app.ChannelSliderLabel.Text = 'Channel Slider';
            app.ChannelSlider = uislider(app.UIFigure);
            app.ChannelSlider.Limits = [1 64];
            app.ChannelSlider.ValueChangingFcn = createCallbackFcn(app, @ChannelSliderValueChanging, true);
            app.ChannelSlider.Value = 1;
            app.RunCSPButton = uibutton(app.UIFigure, 'push');
            app.RunCSPButton.ButtonPushedFcn = createCallbackFcn(app, @RunCSPButtonPushed, true);
            app.RunCSPButton.Text = 'Run CSP';
            app.SubjectSliderLabel = uilabel(app.UIFigure);
            app.SubjectSliderLabel.Text = 'Subject Slider';
            app.SubjectSlider = uislider(app.UIFigure);
            app.SubjectSlider.Limits = [1 109];
            app.SubjectSlider.ValueChangingFcn = createCallbackFcn(app, @SubjectSliderValueChanging, true);
            app.SubjectSlider.Value = 1;
            app.RecordingnumberSliderLabel = uilabel(app.UIFigure);
            app.RecordingnumberSliderLabel.Text = 'Recording number';
            app.RecordingnumberSlider = uislider(app.UIFigure);
            app.RecordingnumberSlider.Limits = [1 14];
            app.RecordingnumberSlider.ValueChangedFcn = createCallbackFcn(app, @RecordingnumberSliderValueChanged, true);
            app.RecordingnumberSlider.ValueChangingFcn = createCallbackFcn(app, @RecordingnumberSliderValueChanging, true);
            app.RecordingnumberSlider.Value = 1;
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RecNameLabel = uilabel(app.UIFigure);
            app.RecNameLabel.Text = 'RecNameLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end